classdef Bill_showing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UITable    matlab.ui.control.Table
        OKButton   matlab.ui.control.Button
        BILLLabel  matlab.ui.control.Label
        Label1     matlab.ui.control.Label
        Label2     matlab.ui.control.Label
    end

    
    properties (Access = public)
        Demo;
        Each_person_pay;
        Orders;
        used_coupon;
        use_coupon_id;
        total_pay;
        
        num_people;
        num_discount;
        freight;
        packing_fee;
        
        bill_table;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Demo)
            app.Demo = Demo;
            app.Each_person_pay = Demo.Each_person_pay;
            app.Orders = Demo.Orders;
            
            app.num_people = Demo.num_people;
            app.num_discount = Demo.num_discount;
            app.freight = Demo.freight;
            app.packing_fee = Demo.packing_fee;
            app.used_coupon = Demo.used_coupon;
            app.total_pay = Demo.total_pay;
            app.use_coupon_id = Demo.use_coupon_id;
            test = app.Each_person_pay(1)
            
            
            app.bill_table = cell(app.num_people+3,4); % row: freight,coupon,total
            
            for id = 1:app.num_people
                person_name = ['Person ',num2str(id)];
                app.bill_table(id,1) = cellstr(person_name);
                
                unit_price = app.Demo.get_person_cost(id);
                packing = app.Demo.get_person_packing(id);
                price_txt = [num2str(unit_price),'+',num2str(packing)];
                app.bill_table(id,2) = cellstr(price_txt);
                
                final_pay = app.Each_person_pay(id);
                app.bill_table(id,3) = cellstr(num2str(final_pay));
            end
            
            app.bill_table(app.num_people+1,1) = cellstr('Freight');
            app.bill_table(app.num_people+1,3) = cellstr(num2str(app.freight));
            
            app.bill_table(app.num_people+2,1) = cellstr('Coupon');
            coupon_txt = ['-',num2str(app.used_coupon)]
            app.bill_table(app.num_people+2,3) = cellstr(coupon_txt);
            
            app.bill_table(app.num_people+3,1) = cellstr('Total');
            app.bill_table(app.num_people+3,3) = cellstr(num2str(app.total_pay));
            
            for i = 1:length(app.use_coupon_id)
                txt = 'Use coupon';
                id = app.use_coupon_id(i);
                app.bill_table(id,4) = cellstr(txt);
            end
            
            app.UITable.Data = app.bill_table;
            
            app.Label1.Text = app.Demo.note_txt1;
            app.Label2.Text = app.Demo.note_txt2;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Person'; 'unit-price'; 'Payment'};
            app.UITable.RowName = {};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.BILLLabel = uilabel(app.UIFigure);
            app.BILLLabel.Text = 'BILL';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bill_showing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end