classdef GetNum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofpeopleSpinnerLabel    matlab.ui.control.Label
        NumberofpeopleSpinner         matlab.ui.control.Spinner
        FreightEditFieldLabel         matlab.ui.control.Label
        FreightEditField              matlab.ui.control.NumericEditField
        NumberofdiscountSpinnerLabel  matlab.ui.control.Label
        NumberofdiscountSpinner       matlab.ui.control.Spinner
        PackingFeeEditFieldLabel      matlab.ui.control.Label
        PackingFeeEditField           matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        Demo;
        
        num_people = 0;
        num_discount = 0;
        freight = 0;
        packing_fee = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Demo)
            app.Demo = Demo;
        end

        % Value changed function: NumberofpeopleSpinner
        function NumberofpeopleSpinnerValueChanged(app, event)
            value = app.NumberofpeopleSpinner.Value;
            app.num_people = value;
        end

        % Value changed function: NumberofdiscountSpinner
        function NumberofdiscountSpinnerValueChanged(app, event)
            value = app.NumberofdiscountSpinner.Value;
            app.num_discount = value;
        end

        % Value changed function: FreightEditField
        function FreightEditFieldValueChanged(app, event)
            value = app.FreightEditField.Value;
            app.freight = value;
        end

        % Value changed function: PackingFeeEditField
        function PackingFeeEditFieldValueChanged(app, event)
            value = app.PackingFeeEditField.Value;
            app.packing_fee = value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            app.Demo.set_num_infor(app.num_people,app.num_discount,app.freight,app.packing_fee);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NumberofpeopleSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofpeopleSpinnerLabel.Text = 'Number of people';
            app.NumberofpeopleSpinner = uispinner(app.UIFigure);
            app.NumberofpeopleSpinner.Limits = [0 Inf];
            app.NumberofpeopleSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofpeopleSpinnerValueChanged, true);
            app.FreightEditFieldLabel = uilabel(app.UIFigure);
            app.FreightEditFieldLabel.Text = 'Freight';
            app.FreightEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreightEditField.Limits = [0 Inf];
            app.FreightEditField.ValueChangedFcn = createCallbackFcn(app, @FreightEditFieldValueChanged, true);
            app.NumberofdiscountSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofdiscountSpinnerLabel.Text = 'Number of discount';
            app.NumberofdiscountSpinner = uispinner(app.UIFigure);
            app.NumberofdiscountSpinner.Limits = [0 Inf];
            app.NumberofdiscountSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofdiscountSpinnerValueChanged, true);
            app.PackingFeeEditFieldLabel = uilabel(app.UIFigure);
            app.PackingFeeEditFieldLabel.Text = 'Packing Fee';
            app.PackingFeeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PackingFeeEditField.Limits = [0 Inf];
            app.PackingFeeEditField.ValueChangedFcn = createCallbackFcn(app, @PackingFeeEditFieldValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetNum(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end