classdef SetOrder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OrderTable               matlab.ui.control.Table
        DiscountTable            matlab.ui.control.Table
        PersonDropDownLabel      matlab.ui.control.Label
        PersonDropDown           matlab.ui.control.DropDown
        UnitpriceEditFieldLabel  matlab.ui.control.Label
        UnitpriceEditField       matlab.ui.control.NumericEditField
        CouponEditFieldLabel     matlab.ui.control.Label
        CouponEditField          matlab.ui.control.NumericEditField
        DiscountDropDownLabel    matlab.ui.control.Label
        DiscountDropDown         matlab.ui.control.DropDown
        OverEditFieldLabel       matlab.ui.control.Label
        OverEditField            matlab.ui.control.NumericEditField
        MinusEditFieldLabel      matlab.ui.control.Label
        MinusEditField           matlab.ui.control.NumericEditField
        NextButton               matlab.ui.control.Button
        QuantitySpinnerLabel     matlab.ui.control.Label
        QuantitySpinner          matlab.ui.control.Spinner
        AddButton                matlab.ui.control.Button
        AddButton_2              matlab.ui.control.Button
        SetWeightButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        num_people;
        num_discount;
        packing_fee;
        
        set_weight_state = 0;
        
        Order_Table; %cell
        Discount_Table; %cell
        
        % -------
        save_person_infor = [0,0,0,0]; %[id,quantity,price,coupon]
        save_discount_infor = [0,0,0]; %[id,over,minus]
        % -------
        Discounts; %{[id,over,minus], [id,over,minus] ...}
        Orders; %{[person_id, cost, packing_fee]}
        Coupons; %{[person_id, coupon value]}
        Weights; %[1,1,1]
        
        % -------
        Demo;
        set_weight_UI;
    end
    
    methods (Access = public)
        
        function update_person_table(app)
            fprintf('In update_person_table\n')
            person_id = app.save_person_infor(1);
            person_quantity = app.save_person_infor(2);
            person_price = app.save_person_infor(3);
            person_coupon = app.save_person_infor(4);
            
            row = person_id;
            app.Orders{row}(2) = person_quantity*person_price;
            app.Orders{row}(3) = person_quantity*app.packing_fee;
            app.Coupons{row}(2) = person_coupon;
            
            fprintf('Orders\n')
            app.show_three_table(app.Orders,1);
            fprintf('Coupons\n')
            app.show_three_table(app.Coupons,1);
        end
        
        function update_discount_table(app)
            fprintf('In update_discount_table\n')
            row = app.save_discount_infor(1);
            over_money = app.save_discount_infor(2);
            minus_money = app.save_discount_infor(3);
            
            app.Discounts{row}(2) = over_money;
            app.Discounts{row}(3) = minus_money;
            
            fprintf('Discounts\n')
            app.show_three_table(app.Discounts,2);
        end
        
        function show_three_table(app,table,type)
            switch type
                case 1
                    num_row = app.num_people;
                case 2
                    num_row = app.num_discount;
            end
            for i = 1:num_row
                r = cell2mat(table(i))
            end
        end
        
        function re = check_if_full(app,Table,type) %1--full; 0--empty
            switch type
                case 1
                    num_row = app.num_people;
                case 2
                    num_row = app.num_discount;
            end
            re = 1;
            for i = 1:num_row
                if isempty(cell2mat(Table(i,2))) == 1
                    re = 0;
                    break
                end
            end
        end
        
        function update_next_state(app)
            app.NextButton.Enable = 'off';
            if app.check_if_full(app.Order_Table,1)==1 && app.check_if_full(app.Discount_Table,2)==1 && app.set_weight_state==0
                app.NextButton.Enable = 'on';
            end
        end
    
        function initialize_person(app)
            app.UnitpriceEditField.Value = 0;
            app.CouponEditField.Value = 0;
            app.QuantitySpinner.Value = 0;
        end
        
        function initialize_discount(app)
            app.OverEditField.Value = 0;
            app.MinusEditField.Value = 0;
        end
        
        function set_weight(app,weight_group)
            app.Order_Table = app.OrderTable.Data;
            app.Weights = weight_group;
            for i = 1:app.num_people
                
                app.Order_Table(i,5) = cellstr(num2str(app.Weights(i)));
            end
            app.OrderTable.Data = app.Order_Table
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Demo, num_people, num_discount, packing_fee)
            app.num_people = num_people;
            app.num_discount = num_discount;
            app.Demo = Demo;
            app.packing_fee = packing_fee;
            
            app.Order_Table = cell(num_people,5);
            app.Discount_Table = cell(num_discount,3);
            
            for i = 1:num_people
                person_name = ['Person ',num2str(i)];
                app.PersonDropDown.Items{end+1}=person_name;
                app.Order_Table(i,1) = cellstr(person_name);
                app.Order_Table(i,5) = cellstr(num2str(1));
            end
            
            for i = 1:num_discount
                discount_id = num2str(i);
                app.DiscountDropDown.Items{end+1}=discount_id;
                app.Discount_Table(i,1) = cellstr(discount_id);
            end
            
            app.OrderTable.Data = app.Order_Table;
            app.DiscountTable.Data = app.Discount_Table;
            
            app.Discounts = {}; %{[id,over,minus], [id,over,minus] ...}
            app.Orders = {}; %{[person_id, cost, packing_fee]}
            app.Coupons = {}; %{[person_id, coupon value]}
            app.Weights = zeros(1,app.num_people);
            % initialize three table
            for i = 1:num_people
                app.Orders{end+1}=[i,0,0];
                app.Coupons{end+1}=[i,0];
                app.Weights(i)=1;
            end
            
            for i = 1:num_discount
                app.Discounts{end+1}=[i,0,0];
            end
            

        end

        % Value changed function: PersonDropDown
        function PersonDropDownValueChanged(app, event)
            value = app.PersonDropDown.Value;
            app.AddButton.Enable = 'off';
            for i = 1:app.num_people
                p_name = ['Person ',num2str(i)];
                switch value
                    case 'Option'
                        app.AddButton.Enable = 'off';
                    case p_name
                        app.AddButton.Enable = 'on';
                        app.save_person_infor(1)=i;
                end
            end
            app.initialize_person();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.Order_Table = app.OrderTable.Data;
            
            app.save_person_infor(2) = app.QuantitySpinner.Value;
            app.save_person_infor(3) = app.UnitpriceEditField.Value;
            app.save_person_infor(4) = app.CouponEditField.Value;
            
            person_id = app.save_person_infor(1);
            person_quantity = app.save_person_infor(2);
            person_price = app.save_person_infor(3);
            person_coupon = app.save_person_infor(4);
            
            p_name = ['Person ',num2str(person_id)];
            row = app.Demo.find_row_in_cell(p_name,app.Order_Table,app.num_people);
            
            app.Order_Table(row,2)=cellstr(num2str(person_price));
            app.Order_Table(row,3)=cellstr(num2str(person_quantity));
            app.Order_Table(row,4)=cellstr(['-',num2str(person_coupon)]);
            
            app.OrderTable.Data = app.Order_Table;
            app.update_person_table();
            app.update_next_state();
            
        end

        % Value changed function: DiscountDropDown
        function DiscountDropDownValueChanged(app, event)
            value = app.DiscountDropDown.Value
            app.AddButton_2.Enable = 'off';
            %[id,over,minus]
            for i = 1:app.num_discount
                d_name = num2str(i);
                switch value
                    case 'Option'
                        app.AddButton_2.Enable = 'off';
                    case d_name
                        app.AddButton_2.Enable = 'on';
                        app.save_discount_infor(1)=i;
                end
            end
            app.initialize_discount();
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            app.Discount_Table = app.DiscountTable.Data;
            
            app.save_discount_infor(2)=app.OverEditField.Value;
            app.save_discount_infor(3)=app.MinusEditField.Value;
            
            discount_id = app.save_discount_infor(1);
            discount_over = app.save_discount_infor(2);
            discount_minus = app.save_discount_infor(3);
            
            d_name = num2str(discount_id);
            row = app.Demo.find_row_in_cell(d_name,app.Discount_Table,app.num_discount);
            
            app.Discount_Table(row,2)=cellstr(num2str(discount_over));
            app.Discount_Table(row,3)=cellstr(num2str(discount_minus));
            
            app.DiscountTable.Data = app.Discount_Table;
            app.update_discount_table();
            app.update_next_state();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Discounts; %{[id,over,minus], [id,over,minus] ...}
            % Orders; %{[person_id, cost, packing_fee]}
            % Coupons; %{[person_id, coupon value]}
            
            % delete id in Discounts then sort
            new_discounts = {};
            for i = 1:length(app.Discounts)
                old_group = cell2mat(app.Discounts(i));
                new_group = [old_group(2),old_group(3)];
                new_discounts{end+1}=new_group;
            end
            sorted_discounts = app.Demo.sort_cell(new_discounts);
            
            app.Demo.Get_three_table(sorted_discounts,app.Orders,app.Coupons,app.Weights);
         
            app.Demo.calculation();
            app.Demo.show_bill();
            app.Demo.show_weight_bill();
            
%             delete(app);
        end

        % Button pushed function: SetWeightButton
        function SetWeightButtonPushed(app, event)
            app.set_weight_UI = SetWeight(app.Demo);
            app.set_weight_state = 1;
            app.update_next_state();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OrderTable = uitable(app.UIFigure);
            app.OrderTable.ColumnName = {'Person'; 'Unit-price'; 'quantity'; 'Coupon'; 'Weight'};
            app.OrderTable.RowName = {};
            app.DiscountTable = uitable(app.UIFigure);
            app.DiscountTable.ColumnName = {'ID'; 'Over'; 'Minus'};
            app.DiscountTable.RowName = {};
            app.PersonDropDownLabel = uilabel(app.UIFigure);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.UIFigure);
            app.PersonDropDown.Items = {'Option'};
            app.PersonDropDown.ValueChangedFcn = createCallbackFcn(app, @PersonDropDownValueChanged, true);
            app.PersonDropDown.Value = 'Option';
            app.UnitpriceEditFieldLabel = uilabel(app.UIFigure);
            app.UnitpriceEditFieldLabel.Text = 'Unit-price';
            app.UnitpriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CouponEditFieldLabel = uilabel(app.UIFigure);
            app.CouponEditFieldLabel.Text = 'Coupon';
            app.CouponEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiscountDropDownLabel = uilabel(app.UIFigure);
            app.DiscountDropDownLabel.Text = 'Discount';
            app.DiscountDropDown = uidropdown(app.UIFigure);
            app.DiscountDropDown.Items = {'Option'};
            app.DiscountDropDown.ValueChangedFcn = createCallbackFcn(app, @DiscountDropDownValueChanged, true);
            app.DiscountDropDown.Value = 'Option';
            app.OverEditFieldLabel = uilabel(app.UIFigure);
            app.OverEditFieldLabel.Text = 'Over';
            app.OverEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinusEditFieldLabel = uilabel(app.UIFigure);
            app.MinusEditFieldLabel.Text = 'Minus';
            app.MinusEditField = uieditfield(app.UIFigure, 'numeric');
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.QuantitySpinnerLabel = uilabel(app.UIFigure);
            app.QuantitySpinnerLabel.Text = 'Quantity';
            app.QuantitySpinner = uispinner(app.UIFigure);
            app.QuantitySpinner.Limits = [0 Inf];
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.UIFigure, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Enable = 'off';
            app.AddButton_2.Text = 'Add';
            app.SetWeightButton = uibutton(app.UIFigure, 'push');
            app.SetWeightButton.ButtonPushedFcn = createCallbackFcn(app, @SetWeightButtonPushed, true);
            app.SetWeightButton.Text = 'Set Weight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetOrder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end