classdef SetWeight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        OKButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        num_people; % Description
        weight;
        
        Table_cell;
        
        %----UI-----
        Demo;
        set_order_UI;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Demo)
            app.num_people = Demo.num_people;
            app.set_order_UI = Demo.set_order_UI;
            
            app.weight = Demo.set_order_UI.Weights;
            app.Table_cell = cell(app.num_people,2);
            
            for i = 1:app.num_people
                person_name = ['Person ',num2str(i)];
                app.Table_cell(i,1) = cellstr(person_name);
                app.Table_cell(i,2) = cellstr(num2str(app.weight(i)));
            end
            
            app.UITable.Data = app.Table_cell;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Table_cell = app.UITable.Data;
            
            for i = 1:app.num_people
                w = cell2mat(app.Table_cell(i,2));
                app.weight(i)= str2num(w);
            end
            
            app.set_order_UI.set_weight(app.weight);
            app.set_order_UI.set_weight_state = 0;
            app.set_order_UI.update_next_state();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Person'; 'Weight'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetWeight(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end